classdef MBTA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EnterLocationEditFieldLabel  matlab.ui.control.Label
        EnterLocationEditField       matlab.ui.control.EditField
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
        MBTALabel                    matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
    end

    methods (Access = private)
        
    end

    methods (Access = private)

        % Value changed function: EnterLocationEditField
        function EnterLocationEditFieldValueChanged(app, event)
            value = app.EnterLocationEditField.Value;
            try
                [startlat, startlng] = findlatlng(app, value);
                stopsdata = findstops(app, startlat, startlng);
                distances = finddistances(app, startlat, startlng, stopsdata);
                [sortstops, sortdistances, sortids] = sortsandn(app, stopsdata, distances);
                putstopsinrows(app, sortstops, sortdistances);
                createPlot(app, sortstops, sortdistances, sortids);
                createmap(app, startlat, startlng, stopsdata, sortstops);
                setmap(app);
                writestops(app, sortstops, sortdistances);
                app.UITable.Enable = 'on';
            catch
                mbtareset(app);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EnterLocationEditFieldLabel = uilabel(app.UIFigure);
            app.EnterLocationEditFieldLabel.Text = 'Enter Location';
            app.EnterLocationEditField = uieditfield(app.UIFigure, 'text');
            app.EnterLocationEditField.ValueChangedFcn = createCallbackFcn(app, @EnterLocationEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'T Stop'; 'Distance'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Closest Stops')
            xlabel(app.UIAxes, 'Meters')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 100 200 300 400 500 600];
            app.UIAxes.YTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.UIAxes.Visible = 'off';
            app.MBTALabel = uilabel(app.UIFigure);
            app.MBTALabel.Text = 'MBTA';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MBTA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end